classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        TabGroup    matlab.ui.container.TabGroup
        Tab         matlab.ui.container.Tab
        Label_4     matlab.ui.control.Label
        Button      matlab.ui.control.Button
        Tab_2       matlab.ui.container.Tab
        Label_2     matlab.ui.control.Label
        Imgg        matlab.ui.control.Image
        Imgg2       matlab.ui.control.Image
        Tab_3       matlab.ui.container.Tab
        Label       matlab.ui.control.Label
        Imgg3       matlab.ui.control.Image
        Tab_4       matlab.ui.container.Tab
        Label_3     matlab.ui.control.Label
        TextArea_2  matlab.ui.control.TextArea
        TextArea    matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
% Загрузка изображений
img1 = imread('ben1.jpg');
img2 = imread('ben4.jpg');

% Отображение изображений на интерфейсе
app.Imgg.ImageSource = img1;
app.Imgg2.ImageSource = img2;
app.Imgg.Visible = 'on';
app.Imgg2.Visible = 'on';
app.Imgg3.Visible = 'on';
app.TextArea.Visible = 'on';
app.TextArea_2.Visible = 'on';
app.Label.Visible = 'off';
app.Label_2.Visible = 'off';
app.Label_3.Visible = 'off';
app.Label_4.Visible = 'off';

% Переход на вкладку Tab_2 в приложении
app.TabGroup.SelectedTab = app.Tab_2;

% Проверка на пустоту изображений
if isempty(img1) || isempty(img2)
    error('Ошибка загрузки изображений.');
end

% Преобразование изображений в оттенки серого
gray_img1 = rgb2gray(img1);
gray_img2 = rgb2gray(img2);

% Параметры для метода Харриса
sigma = 2;     
thresh = 500; 

% Вычисление градиентов
smoothed_img = imgaussfilt(double(gray_img1), sigma);
[Ix, Iy] = gradient(smoothed_img);

% Фильтрация градиентов с использованием гауссовского фильтра
gauss_filter = fspecial('gaussian', round(6*sigma), sigma);
Sx2 = imfilter(Ix .^ 2, gauss_filter, 'conv', 'replicate');
Sy2 = imfilter(Iy .^ 2, gauss_filter, 'conv', 'replicate');
Sxy = imfilter(Ix .* Iy, gauss_filter, 'conv', 'replicate');

% Вычисление элементов матрицы гессиана
det_M = (Sx2 .* Sy2) - (Sxy .^ 2);
trace_M = Sx2 + Sy2;
harris_response = det_M - 0.04 * (trace_M .^ 2);

% Отбор углов Харриса по порогу
harris_response(harris_response < thresh) = 0;

% Обнаружение углов на изображениях
corners = imregionalmax(harris_response);
[keypoints_y, keypoints_x] = find(corners);
keypoints = [keypoints_x, keypoints_y];

% Размер патча для сопоставления
patch_size = 4;

% Инициализация списка сопоставлений
matches = [];

% Проход по всем ключевым точкам первого изображения
for i = 1:size(keypoints, 1)
    kp1_x = keypoints(i, 1);
    kp1_y = keypoints(i, 2);
    if kp1_x < floor(patch_size / 2) || kp1_x > size(gray_img1, 2) - floor(patch_size / 2) || ...
            kp1_y < floor(patch_size / 2) || kp1_y > size(gray_img1, 1) - floor(patch_size / 2)
        continue; % Пропускаем краевые точки
    end
    
    % Выделение патча изображения 1
    patch1 = gray_img1(kp1_y-floor(patch_size/2):kp1_y+floor(patch_size/2)-1, ...
                       kp1_x-floor(patch_size/2):kp1_x+floor(patch_size/2)-1);
    
    % Поиск наилучшего сопоставления на изображении 2
    best_match_idx = 0;
    best_match_dist = inf;
    for j = 1:size(keypoints, 1)
        kp2_x = keypoints(j, 1);
        kp2_y = keypoints(j, 2);
        if kp2_x < floor(patch_size / 2) || kp2_x > size(gray_img2, 2) - floor(patch_size / 2) || ...
                kp2_y < floor(patch_size / 2) || kp2_y > size(gray_img2, 1) - floor(patch_size / 2)
            continue; % Пропускаем краевые точки
        end
        
        % Выделение патча изображения 2
        patch2 = gray_img2(kp2_y-floor(patch_size/2):kp2_y+floor(patch_size/2)-1, ...
                           kp2_x-floor(patch_size/2):kp2_x+floor(patch_size/2)-1);
        
        % Вычисление расстояния между патчами (дескриптор)
        dist = norm(double(patch1(:)) - double(patch2(:)));
        if dist < best_match_dist
            best_match_dist = dist;
            best_match_idx = j;
        end
    end
    
    % Добавление сопоставления в список
    matches = [matches; [i, best_match_idx]];
end


% Количество найденных сопоставлений
num_matches = size(matches, 1);

% Подготовка изображений для отображения сопоставлений
img2_resized = imresize(img2, [size(img1, 1), size(img1, 2)]);
merged_img = cat(2, img1, img2_resized);
keypoints_img2_translated = keypoints;
keypoints_img2_translated(:, 1) = keypoints_img2_translated(:, 1) + size(img1, 2);

% Создание временного изображения для визуализации сопоставлений
h = figure('Visible', 'off');
imshow(merged_img);
hold on;

% Визуализация сопоставлений
for i = 1:size(matches, 1)
    idx1 = matches(i, 1);
    idx2 = matches(i, 2);
    kp1 = keypoints(idx1, :);
    kp2 = keypoints_img2_translated(idx2, :);
    plot([kp1(1), kp2(1)], [kp1(2), kp2(2)], 'p', 'LineWidth', 1);
    plot([kp1(1), kp2(1)], [kp1(2), kp2(2)], 'y', 'LineWidth', 1);
end

hold off;

% Получение данных о временном изображении и закрытие его
frame = getframe(h);
imgg3_data = frame.cdata;
close(h);

% Отображение результата сопоставления и информации о нем на интерфейсе приложения
app.Imgg3.ImageSource = imgg3_data;
app.TextArea.Value = {'Количество совпадений:', num2str(num_matches)};
app.TextArea_2.Value = 'Координаты ключевых точек:';
for i = 1:size(keypoints, 1)
    app.TextArea_2.Value = [app.TextArea_2.Value; ['[x,y] = ', num2str(keypoints(i, :))]];
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Главная';
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Стартуем';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = 'Нажмите на кнопочку';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Исходн. изобр.';
            app.Imgg2 = uiimage(app.Tab_2);
            app.Imgg2.Visible = 'off';
            app.Imgg = uiimage(app.Tab_2);
            app.Imgg.Visible = 'off';
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = 'Нажмите на кнопку';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Сравн. изобр.';
            app.Imgg3 = uiimage(app.Tab_3);
            app.Imgg3.Visible = 'off';
            app.Label = uilabel(app.Tab_3);
            app.Label.Text = {'ПОЖАЛУЙСТА'; 'НАЖМИ НА КНОПКУ!!!'};
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Доп. инф.';
            app.TextArea = uitextarea(app.Tab_4);
            app.TextArea.Visible = 'off';
            app.TextArea_2 = uitextarea(app.Tab_4);
            app.TextArea_2.Visible = 'off';
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = {'Я ПРЕДУПРЕЖДАЮ ВАС'; 'НАЖМИТЕ НА КНОПКУ!!!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end